classdef project1colored < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Image_2                      matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        ResetGraphButton             matlab.ui.control.Button
        Group4Label                  matlab.ui.control.Label
        ResultsPanel                 matlab.ui.container.Panel
        ShearValuesLabel             matlab.ui.control.Label
        Thetas2EditField             matlab.ui.control.NumericEditField
        Thetas2EditFieldLabel        matlab.ui.control.Label
        PrincipalstressesLabel       matlab.ui.control.Label
        RotationStressesLabel        matlab.ui.control.Label
        Thetas1EditField             matlab.ui.control.NumericEditField
        Thetas1EditFieldLabel        matlab.ui.control.Label
        MaximumShearEditField        matlab.ui.control.NumericEditField
        MaximumShearEditFieldLabel   matlab.ui.control.Label
        thetap2EditField             matlab.ui.control.NumericEditField
        thetap2EditFieldLabel        matlab.ui.control.Label
        sigma2EditField              matlab.ui.control.NumericEditField
        sigma2EditFieldLabel         matlab.ui.control.Label
        thetap1EditField             matlab.ui.control.NumericEditField
        thetap1EditFieldLabel        matlab.ui.control.Label
        Sigma1EditField              matlab.ui.control.NumericEditField
        Sigma1EditFieldLabel         matlab.ui.control.Label
        shear1EditField              matlab.ui.control.NumericEditField
        shear1EditFieldLabel         matlab.ui.control.Label
        sigmay1EditField             matlab.ui.control.NumericEditField
        sigmay1EditFieldLabel        matlab.ui.control.Label
        sigmax1EditField             matlab.ui.control.NumericEditField
        sigmax1EditFieldLabel        matlab.ui.control.Label
        InputparametersPanel         matlab.ui.container.Panel
        DrawcircleandcalculateparametersButton  matlab.ui.control.Button
        RotationangleEditField       matlab.ui.control.NumericEditField
        RotationangleEditFieldLabel  matlab.ui.control.Label
        ShearEditField               matlab.ui.control.NumericEditField
        ShearEditFieldLabel          matlab.ui.control.Label
        sigma_yEditField             matlab.ui.control.NumericEditField
        sigma_yEditFieldLabel        matlab.ui.control.Label
        sigma_xEditField             matlab.ui.control.NumericEditField
        sigmaxLabel                  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DrawcircleandcalculateparametersButton
        function DrawcircleandcalculateparametersButtonPushed(app, event)
%Inputs
sigma_x = app.sigma_xEditField.Value;
sigma_y = app.sigma_yEditField.Value;
tau = app.ShearEditField.Value;
angle_degrees = app.RotationangleEditField.Value;

angle_radians = deg2rad(angle_degrees);
%Outputs
sigmax1 = sigma_x * cos(angle_radians)^2 + sigma_y * sin(angle_radians)^2 + 2 * tau * sin(angle_radians) * cos(angle_radians);
sigmay1 = (sigma_x + sigma_y) / 2 - ((sigma_x - sigma_y) / 2) * cos(2 * angle_radians) - tau * sin(2 * angle_radians);
tau1 = ((sigma_y - sigma_x) / 2) * sin(2 * angle_radians) + tau * cos(2 * angle_radians);

app.sigmax1EditField.Value = sigmax1;
app.sigmay1EditField.Value = sigmay1;
app.shear1EditField.Value = tau1;

%principle stress
sigma_1 = ((sigma_x + sigma_y) / 2) + sqrt(((sigma_x - sigma_y) / 2)^2 + tau^2);
sigma_2 = ((sigma_x + sigma_y) / 2) - sqrt(((sigma_x - sigma_y) / 2)^2 + tau^2);
theta_p1 = atan2d(2 * tau, sigma_x - sigma_y) / 2;

theta_p2 = theta_p1 + 90;

app.Sigma1EditField.Value = sigma_1;
app.sigma2EditField.Value = sigma_2;
app.thetap1EditField.Value = theta_p1;
app.thetap2EditField.Value = theta_p2;

%shear max

shear_max = sqrt(((sigma_x - sigma_y) / 2)^2 + tau^2);
app.MaximumShearEditField.Value = shear_max;
shear_angle1 = theta_p1-45;
shear_angle2 = theta_p1+45;
app.Thetas1EditField.Value = shear_angle1;
app.Thetas2EditField.Value = shear_angle2;

%MOHR CIRCLE

%construction of the circle itself
R = sqrt(((sigma_x - sigma_y) / 2)^2 + tau^2);
theta_Range = 0:0.01:2*pi;
xcenter= (sigma_x + sigma_y) / 2 ;
ycenter = 0;

xcoord= xcenter + R*cos(theta_Range);
ycoord = ycenter + R*sin(theta_Range);

plot(app.UIAxes, xcoord,ycoord);

%drawing x and y axis
x =[sigma_1 sigma_2];
y= 0;
plot(app.UIAxes,x,[y y],'--', "Color",[0.5 0.5 0.5]); %x axis
plot(app.UIAxes, [xcenter xcenter], [shear_max -shear_max], '--', 'Color', [0.5 0.5 0.5]); %y-axis

plot(app.UIAxes,[sigma_x sigma_y], [tau -tau], "Color","b");%line before rotation
plot(app.UIAxes,[sigmax1 sigmay1], [tau1 -tau1], "Color","r");% line after rotation
text(app.UIAxes,sigma_x,tau,"Point A");
text(app.UIAxes,sigma_y, -tau,"Point B");
text(app.UIAxes,sigmax1,tau1,"D");
text(app.UIAxes,sigmay1,-tau1,"C");

%Angle
slope = (-tau - tau)/(sigma_y-sigma_x);
angle_rad = atan(slope); 
angle_deg = rad2deg(angle_rad); 
angle_deg_rounded = round(angle_deg, 2);
text(app.UIAxes, xcenter, angle_deg, ['  ∠ to X-axis: ', num2str(angle_deg_rounded), '°']);

%line after rotation [under construction] NOTE: it Failed
%x1coord= xcenter + R*cos(2*angle_radians);
%y1coord = ycenter + R*sin(2*angle_radians);
%x2coord= xcenter + R*cos(2*(angle_radians)+(pi/2));
%y2coord = ycenter + R*sin(2*(angle_radians)+(pi/2));
%plot(app.UIAxes,[x1coord y1coord],[x2coord y2coord],"Color","r");

        end

        % Button pushed function: ResetGraphButton
        function ResetGraphButtonPushed(app, event)
            cla(app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Mohr Circle')
            xlabel(app.UIAxes, 'Sigma')
            ylabel(app.UIAxes, 'Txy')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XAxisLocation = 'top';
            app.InputparametersPanel = uipanel(app.UIFigure);
            app.InputparametersPanel.Title = 'Input parameters';
            app.sigmaxLabel = uilabel(app.InputparametersPanel);
            app.sigmaxLabel.Text = 'sigma_x';
            app.sigma_xEditField = uieditfield(app.InputparametersPanel, 'numeric');
            app.sigma_xEditField.ValueDisplayFormat = '%.2f';
            app.sigma_yEditFieldLabel = uilabel(app.InputparametersPanel);
            app.sigma_yEditFieldLabel.Text = 'sigma_y';
            app.sigma_yEditField = uieditfield(app.InputparametersPanel, 'numeric');
            app.sigma_yEditField.ValueDisplayFormat = '%.2f';
            app.ShearEditFieldLabel = uilabel(app.InputparametersPanel);
            app.ShearEditFieldLabel.Text = 'Shear';
            app.ShearEditField = uieditfield(app.InputparametersPanel, 'numeric');
            app.ShearEditField.ValueDisplayFormat = '%.2f';
            app.RotationangleEditFieldLabel = uilabel(app.InputparametersPanel);
            app.RotationangleEditFieldLabel.Text = 'Rotation angle';
            app.RotationangleEditField = uieditfield(app.InputparametersPanel, 'numeric');
            app.RotationangleEditField.ValueDisplayFormat = '%.2f';
            app.DrawcircleandcalculateparametersButton = uibutton(app.InputparametersPanel, 'push');
            app.DrawcircleandcalculateparametersButton.ButtonPushedFcn = createCallbackFcn(app, @DrawcircleandcalculateparametersButtonPushed, true);
            app.DrawcircleandcalculateparametersButton.Text = 'Draw circle and calculate parameters';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.sigmax1EditFieldLabel = uilabel(app.ResultsPanel);
            app.sigmax1EditFieldLabel.Text = 'sigma x1';
            app.sigmax1EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.sigmax1EditField.ValueDisplayFormat = '%.2f';
            app.sigmay1EditFieldLabel = uilabel(app.ResultsPanel);
            app.sigmay1EditFieldLabel.Text = 'sigma y1';
            app.sigmay1EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.sigmay1EditField.ValueDisplayFormat = '%.2f';
            app.shear1EditFieldLabel = uilabel(app.ResultsPanel);
            app.shear1EditFieldLabel.Text = 'shear 1';
            app.shear1EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.shear1EditField.ValueDisplayFormat = '%.2f';
            app.Sigma1EditFieldLabel = uilabel(app.ResultsPanel);
            app.Sigma1EditFieldLabel.Text = 'Sigma 1';
            app.Sigma1EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.Sigma1EditField.ValueDisplayFormat = '%.2f';
            app.thetap1EditFieldLabel = uilabel(app.ResultsPanel);
            app.thetap1EditFieldLabel.Text = 'theta p1';
            app.thetap1EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.thetap1EditField.ValueDisplayFormat = '%.2f';
            app.sigma2EditFieldLabel = uilabel(app.ResultsPanel);
            app.sigma2EditFieldLabel.Text = 'sigma 2';
            app.sigma2EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.sigma2EditField.ValueDisplayFormat = '%.2f';
            app.thetap2EditFieldLabel = uilabel(app.ResultsPanel);
            app.thetap2EditFieldLabel.Text = 'theta p2';
            app.thetap2EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.thetap2EditField.ValueDisplayFormat = '%.2f';
            app.MaximumShearEditFieldLabel = uilabel(app.ResultsPanel);
            app.MaximumShearEditFieldLabel.Text = 'Maximum Shear';
            app.MaximumShearEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.MaximumShearEditField.ValueDisplayFormat = '%.2f';
            app.Thetas1EditFieldLabel = uilabel(app.ResultsPanel);
            app.Thetas1EditFieldLabel.Text = 'Theta s1';
            app.Thetas1EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.RotationStressesLabel = uilabel(app.ResultsPanel);
            app.RotationStressesLabel.Text = 'Rotation Stresses';
            app.PrincipalstressesLabel = uilabel(app.ResultsPanel);
            app.PrincipalstressesLabel.Text = 'Principal stresses';
            app.Thetas2EditFieldLabel = uilabel(app.ResultsPanel);
            app.Thetas2EditFieldLabel.Text = 'Theta s2';
            app.Thetas2EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.ShearValuesLabel = uilabel(app.ResultsPanel);
            app.ShearValuesLabel.Text = 'Shear Values';
            app.Group4Label = uilabel(app.UIFigure);
            app.Group4Label.Text = 'Group 4';
            app.ResetGraphButton = uibutton(app.UIFigure, 'push');
            app.ResetGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ResetGraphButtonPushed, true);
            app.ResetGraphButton.Text = 'Reset Graph';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Extras', 'Eng Logo.jpg');
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Extras', 'Helwan Uni Logo.jpeg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project1colored

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end